classdef BatteryMonitorPC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BatteryMonitorPCUIFigure      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ConfigurationTab              matlab.ui.container.Tab
        CurrentSOCEditField           matlab.ui.control.NumericEditField
        CurrentSOCEditFieldLabel      matlab.ui.control.Label
        PollingIntervalsSpinner       matlab.ui.control.Spinner
        PollingIntervalsSpinnerLabel  matlab.ui.control.Label
        BatteryCapacitymAhEditField   matlab.ui.control.NumericEditField
        BatteryCapacityAhLabel        matlab.ui.control.Label
        BatteryChemistryDropDown      matlab.ui.control.DropDown
        BatteryChemistryLabel         matlab.ui.control.Label
        ConnectButton                 matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        COMPortsListBox               matlab.ui.control.ListBox
        COMPortsListBoxLabel          matlab.ui.control.Label
        NumberofCellsSpinner          matlab.ui.control.Spinner
        NumberofCellsSpinnerLabel     matlab.ui.control.Label
        RunTab                        matlab.ui.container.Tab
        LogFileLocationDisplay        matlab.ui.control.TextArea
        LogtoFileLocation             matlab.ui.control.Label
        LogtoFileSwitch               matlab.ui.control.Switch
        LogtoFileSwitchLabel          matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        ChargeTimeEditField           matlab.ui.control.EditField
        ChargeTimeEditFieldLabel      matlab.ui.control.Label
        DischargeTimeEditField        matlab.ui.control.EditField
        DischargeTimeEditFieldLabel   matlab.ui.control.Label
        PowerFlowEditField            matlab.ui.control.EditField
        PowerFlowEditFieldLabel       matlab.ui.control.Label
        EnergyOutmAhEditField         matlab.ui.control.NumericEditField
        EnergyOutmAhEditFieldLabel    matlab.ui.control.Label
        EnergyInmAhEditField          matlab.ui.control.NumericEditField
        EnergyInmAhEditFieldLabel     matlab.ui.control.Label
        WLabel                        matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        StatusLampLabel               matlab.ui.control.Label
        TimeElapsed                   matlab.ui.control.EditField
        TotalTimeElapsedLabel         matlab.ui.control.Label
        EstimatedSOCGauge             matlab.ui.control.Gauge
        EstimatedSOCGaugeLabel        matlab.ui.control.Label
        PowerWGauge                   matlab.ui.control.Gauge
        PowerWGaugeLabel              matlab.ui.control.Label
        CellVoltagesTab               matlab.ui.container.Tab
        CellMeanVEditField            matlab.ui.control.NumericEditField
        CellMeanVEditFieldLabel       matlab.ui.control.Label
        CellDeltaVEditField           matlab.ui.control.NumericEditField
        CellDeltaVEditFieldLabel      matlab.ui.control.Label
        MaxCellEditField              matlab.ui.control.NumericEditField
        MaxCellLabel                  matlab.ui.control.Label
        MinCellEditField              matlab.ui.control.NumericEditField
        MinCellLabel                  matlab.ui.control.Label
        Cell16EditField               matlab.ui.control.NumericEditField
        Cell16EditFieldLabel          matlab.ui.control.Label
        Cell15EditField               matlab.ui.control.NumericEditField
        Cell15EditFieldLabel          matlab.ui.control.Label
        Cell14EditField               matlab.ui.control.NumericEditField
        Cell14EditFieldLabel          matlab.ui.control.Label
        Cell13EditField               matlab.ui.control.NumericEditField
        Cell13EditFieldLabel          matlab.ui.control.Label
        Cell12EditField               matlab.ui.control.NumericEditField
        Cell12EditFieldLabel          matlab.ui.control.Label
        Cell11EditField               matlab.ui.control.NumericEditField
        Cell11EditFieldLabel          matlab.ui.control.Label
        Cell10EditField               matlab.ui.control.NumericEditField
        Cell10EditFieldLabel          matlab.ui.control.Label
        Cell09EditField               matlab.ui.control.NumericEditField
        Cell09EditFieldLabel          matlab.ui.control.Label
        Cell08EditField               matlab.ui.control.NumericEditField
        Cell08EditFieldLabel          matlab.ui.control.Label
        Cell07EditField               matlab.ui.control.NumericEditField
        Cell07EditField_2Label        matlab.ui.control.Label
        Cell06EditField               matlab.ui.control.NumericEditField
        Cell06EditField_3Label        matlab.ui.control.Label
        Cell05EditField               matlab.ui.control.NumericEditField
        Cell05EditField_3Label        matlab.ui.control.Label
        Cell04EditField               matlab.ui.control.NumericEditField
        Cell04EditFieldLabel          matlab.ui.control.Label
        Cell03EditField               matlab.ui.control.NumericEditField
        Cell03EditFieldLabel          matlab.ui.control.Label
        Cell02EditField               matlab.ui.control.NumericEditField
        Cell02EditFieldLabel          matlab.ui.control.Label
        Cell01EditField               matlab.ui.control.NumericEditField
        Cell01EditFieldLabel          matlab.ui.control.Label
        CellVoltageVLabel             matlab.ui.control.Label
        CellTempsTab                  matlab.ui.container.Tab
        Cell16EditField_t_2           matlab.ui.control.NumericEditField
        Cell16EditField_2Label_3      matlab.ui.control.Label
        Cell15EditField_t_2           matlab.ui.control.NumericEditField
        Cell15EditField_2Label_3      matlab.ui.control.Label
        Cell14EditField_t_2           matlab.ui.control.NumericEditField
        Cell14EditField_2Label_3      matlab.ui.control.Label
        Cell13EditField_t_2           matlab.ui.control.NumericEditField
        Cell13EditField_2Label_3      matlab.ui.control.Label
        Cell12EditField_t_2           matlab.ui.control.NumericEditField
        Cell12EditField_2Label_3      matlab.ui.control.Label
        Cell11EditField_t_2           matlab.ui.control.NumericEditField
        Cell11EditField_2Label_3      matlab.ui.control.Label
        Cell10EditField_t_2           matlab.ui.control.NumericEditField
        Cell10EditField_2Label_3      matlab.ui.control.Label
        Cell09EditField_t_2           matlab.ui.control.NumericEditField
        Cell09EditField_2Label_3      matlab.ui.control.Label
        Cell08EditField_t_2           matlab.ui.control.NumericEditField
        Cell08EditField_2Label_3      matlab.ui.control.Label
        Cell07EditField_t_2           matlab.ui.control.NumericEditField
        Cell07EditField_2Label_3      matlab.ui.control.Label
        Cell06EditField_t_2           matlab.ui.control.NumericEditField
        Cell06EditField_2Label_3      matlab.ui.control.Label
        Cell05EditField_t_2           matlab.ui.control.NumericEditField
        Cell05EditField_2Label_3      matlab.ui.control.Label
        Cell04EditField_t_2           matlab.ui.control.NumericEditField
        Cell04EditField_2Label_3      matlab.ui.control.Label
        Cell03EditField_t_2           matlab.ui.control.NumericEditField
        Cell03EditField_2Label_3      matlab.ui.control.Label
        Cell02EditField_t_2           matlab.ui.control.NumericEditField
        Cell02EditField_2Label_3      matlab.ui.control.Label
        Cell01EditField_t_2           matlab.ui.control.NumericEditField
        Cell01EditField_2Label_3      matlab.ui.control.Label
        Cell16EditField_t_1           matlab.ui.control.NumericEditField
        Cell16EditField_2Label_2      matlab.ui.control.Label
        Cell15EditField_t_1           matlab.ui.control.NumericEditField
        Cell15EditField_2Label_2      matlab.ui.control.Label
        Cell14EditField_t_1           matlab.ui.control.NumericEditField
        Cell14EditField_2Label_2      matlab.ui.control.Label
        Cell13EditField_t_1           matlab.ui.control.NumericEditField
        Cell13EditField_2Label_2      matlab.ui.control.Label
        Cell12EditField_t_1           matlab.ui.control.NumericEditField
        Cell12EditField_2Label_2      matlab.ui.control.Label
        Cell11EditField_t_1           matlab.ui.control.NumericEditField
        Cell11EditField_2Label_2      matlab.ui.control.Label
        Cell10EditField_t_1           matlab.ui.control.NumericEditField
        Cell10EditField_2Label_2      matlab.ui.control.Label
        Cell09EditField_t_1           matlab.ui.control.NumericEditField
        Cell09EditField_2Label_2      matlab.ui.control.Label
        Cell08EditField_t_1           matlab.ui.control.NumericEditField
        Cell08EditField_2Label_2      matlab.ui.control.Label
        Cell07EditField_t_1           matlab.ui.control.NumericEditField
        Cell07EditField_3Label        matlab.ui.control.Label
        Cell06EditField_t_1           matlab.ui.control.NumericEditField
        Cell06EditField_2Label_2      matlab.ui.control.Label
        Cell05EditField_t_1           matlab.ui.control.NumericEditField
        Cell05EditField_2Label_2      matlab.ui.control.Label
        Cell04EditField_t_1           matlab.ui.control.NumericEditField
        Cell04EditField_2Label_2      matlab.ui.control.Label
        Cell03EditField_t_1           matlab.ui.control.NumericEditField
        Cell03EditField_2Label_2      matlab.ui.control.Label
        Cell02EditField_t_1           matlab.ui.control.NumericEditField
        Cell02EditField_2Label_2      matlab.ui.control.Label
        Cell01EditField_t_1           matlab.ui.control.NumericEditField
        Cell01EditField_2Label_2      matlab.ui.control.Label
        CellTemperatureBT2degCLabel   matlab.ui.control.Label
        CellTemperatureBT1degCLabel   matlab.ui.control.Label
        LoadTab                       matlab.ui.container.Tab
        TODOLabel                     matlab.ui.control.Label
        LogsTab                       matlab.ui.container.Tab
        TODOLabel_2                   matlab.ui.control.Label
        CellSelectLabel               matlab.ui.control.Label
        ListCell                      matlab.ui.container.CheckBoxTree
        Cell1                         matlab.ui.container.TreeNode
        Cell2                         matlab.ui.container.TreeNode
        Cell3                         matlab.ui.container.TreeNode
        Cell4                         matlab.ui.container.TreeNode
        Cell5                         matlab.ui.container.TreeNode
        Cell6                         matlab.ui.container.TreeNode
        Cell7                         matlab.ui.container.TreeNode
        Cell8                         matlab.ui.container.TreeNode
        VoltageGraph                  matlab.ui.control.UIAxes
        TempGraph                     matlab.ui.control.UIAxes
        PackStats                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Variables
        ratedCurrentCT = 100;   % change according to current sensor specsheet
        userProfile = [];
        connected = false;
        running = false;
        logging = false;
        dataIndex = 0;
        serialConnection = [];
        timerInstanceProc;
        timerInstanceVoltageGraph;
        timerInstanceTempGraph;
        timerInstancePackGraph;
        arduinoData = cell(51,1);
        graphTime = [];
        graphTimeLabel = [];
        dayState = false;
        dayCount = 0;
        numOfCells = 8;
        powerState = 'i';
        power = 0;
        eOut = 0;
        eIn = 0;
        totalTime = 0;
        chgTime = 0;
        dchgTime = 0;
        minCell = 0;
        maxCell = 0;
        deltaCell = 0;
        meanCell = 0;
        cellVoltageRange = [];
        currentCharge = 0;
        estimatedSoc = 0;
        ratedVoltageRange = [2.5, 4.2];
        ratedTempRange = [0 60];
        logFile = [];
        logFilePath = [];
        logFileStruct = [];
        logConfigData = [];
        logRunData = [];
        currentTime = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.userProfile = getenv('USERPROFILE');
            if ~isfolder(app.userProfile + "\Documents\Battery Monitor")
                mkdir(app.userProfile + "\Documents\Battery Monitor")
            end
            % preallocate space for storing measurement data
            app.arduinoData{1} = zeros(6,2160000);
            for i=2:51
                app.arduinoData{i} = zeros(1,2160000);
            end
            app.RefreshButtonPushed();
            appPointer = Pointer(app);
            app.timerInstanceProc = timer;
            app.timerInstanceVoltageGraph = timer;
            app.timerInstanceTempGraph = timer;
            app.timerInstancePackGraph = timer;
            app.timerInstanceProc.Period = 0.5;
            app.timerInstanceVoltageGraph.Period = 1.0;
            app.timerInstanceTempGraph.Period = 1.0;
            app.timerInstancePackGraph.Period = 1.0;
            app.timerInstanceVoltageGraph.StartDelay = 0.25;
            app.timerInstanceTempGraph.StartDelay = 0.5;
            app.timerInstancePackGraph.StartDelay = 0.75;
            app.timerInstanceProc.ExecutionMode = "fixedSpacing";
            app.timerInstanceVoltageGraph.ExecutionMode = "fixedSpacing";
            app.timerInstanceTempGraph.ExecutionMode = "fixedSpacing";
            app.timerInstancePackGraph.ExecutionMode = "fixedSpacing";
            app.timerInstanceProc.TimerFcn = @TimerUpdate;
            app.timerInstanceVoltageGraph.TimerFcn = @updateVoltageGraph;
            app.timerInstanceTempGraph.TimerFcn = @updateTempGraph;
            app.timerInstancePackGraph.TimerFcn = @updatePackGraph;
            app.timerInstanceProc.UserData = appPointer;
            app.timerInstanceVoltageGraph.UserData = appPointer;
            app.timerInstanceTempGraph.UserData = appPointer;
            app.timerInstancePackGraph.UserData = appPointer;
            yyaxis(app.PackStats, "right");
            ylabel(app.PackStats, "Current (A)");
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.COMPortsListBox.Items = serialportlist("available");
            if ~isempty(app.COMPortsListBox.Items)
                app.COMPortsListBox.Value = app.COMPortsListBox.Items(1);
                app.ConnectButton.Enable = "on";
            else
                app.ConnectButton.Enable = "off";
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if app.COMPortsListBox.Value ~= ""
                if ~app.connected
                    if ismember(serialportlist("available"), app.COMPortsListBox.Value)
                        app.serialConnection = serialport(app.COMPortsListBox.Value, 115200,Timeout=2);
                        configureTerminator(app.serialConnection,"CR");
                        flush(app.serialConnection);
                        app.currentTime = tic;
                        while toc(app.currentTime) < 2
                        end
                        write(app.serialConnection, char(((app.numOfCells - 1)*16)+3), "char")
                        app.RefreshButton.Enable = "off";
                        app.COMPortsListBox.Enable = "off";
                        app.PollingIntervalsSpinner.Enable = "off";
                        app.NumberofCellsSpinner.Enable = "off";
                        app.BatteryChemistryDropDown.Enable = "off";
                        app.ConnectButton.Text = "Disconnect";
                        app.RunButton.Enable = "on";
                        app.LogtoFileSwitch.Enable = "on";
                        app.logConfigData = [app.numOfCells, app.BatteryCapacitymAhEditField.Value];
                        app.StatusLamp.Color = [1 0 0];
                        app.connected = true;
                        start(app.timerInstanceProc);
                    end
                else
                    stop(app.timerInstanceProc);
                    write(app.serialConnection, "0", "char")
                    app.serialConnection = [];
                    app.RefreshButton.Enable = "on";
                    app.COMPortsListBox.Enable = "on";
                    app.PollingIntervalsSpinner.Enable = "on";
                    app.NumberofCellsSpinner.Enable = "on";
                    app.BatteryChemistryDropDown.Enable = "on";
                    app.ConnectButton.Text = "Connect";
                    app.running = true;
                    app.RunButtonPushed();
                    app.RunButton.Enable = "off";
                    app.LogtoFileSwitch.Enable = "off";
                    app.StatusLamp.Color = [0 1 1];
                    app.ChargeTimeEditField.Value = "";
                    app.DischargeTimeEditField.Value = "";
                    app.TimeElapsed.Value = "";
                    app.EnergyInmAhEditField.Value = 0;
                    app.EnergyOutmAhEditField.Value = 0;
                    app.PowerFlowEditField.Value = "N/A";
                    app.WLabel.Text = "00.00 W";
                    app.connected = false;
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.running = ~app.running;
            if app.running
                app.dataIndex = 0;
                app.RunButton.Text = "Stop";
                app.StatusLamp.Color = [0 1 0];
                app.BatteryCapacitymAhEditField.Enable = "off";
                app.BatteryChemistryDropDown.Enable = "off";
                app.CurrentSOCEditField.Enable = "off";
                app.LogtoFileSwitch.Enable = "off";
                app.eOut = 0;
                app.eIn = 0;
                app.totalTime = 0;
                app.chgTime = 0;
                app.dchgTime = 0;
                app.deltaCell = 0;
                app.meanCell = 0;
                app.minCell = 0;
                app.maxCell = 0;
                app.currentCharge = (app.CurrentSOCEditField.Value/100)*app.BatteryCapacitymAhEditField.Value;
                if strcmp(app.LogtoFileSwitch.Value, "On")
                    app.logFileStruct = fopen(string(app.logFilePath) + string(app.logFile), "a");
                    if app.logFileStruct == -1
                        app.logging = false;
                        app.LogtoFileSwitch.Value = "Off";
                        app.LogFileLocationDisplay.Value = "";
                        app.LogFileLocationDisplay.Tooltip = "";
                        msgbox("Failed to create log file.", "Failed to create log file", "error");
                    else
                        fprintf(app.logFileStruct, "%d,%d,%s\n", app.logConfigData, app.BatteryChemistryDropDown.Value);
                        fclose(app.logFileStruct);
                    end
                end
                app.estimatedSoc = 0;
                start(app.timerInstanceVoltageGraph);
                start(app.timerInstanceTempGraph);
                start(app.timerInstancePackGraph);
            else
                stop(app.timerInstanceVoltageGraph);
                stop(app.timerInstanceTempGraph);
                stop(app.timerInstancePackGraph);
                app.RunButton.Text = "Start";
                app.StatusLamp.Color = [1 0 0];
                app.BatteryCapacitymAhEditField.Enable = "on";
                app.BatteryChemistryDropDown.Enable = "on";
                app.CurrentSOCEditField.Enable = "on";
                app.LogtoFileSwitch.Enable = "on";
                app.PowerWGauge.Value = 0;
                app.PowerWGauge.Limits = [-50 50];
                app.LogtoFileSwitch.Value = "Off";
                app.LogtoFileLocation.Text = "Log to: <path_to_file>";
                app.logging = false;
                app.logFile = [];
                app.logFilePath = [];
            end
        end

        % Value changed function: PollingIntervalsSpinner
        function PollingIntervalsSpinnerValueChanged(app, event)
            app.timerInstanceProc.Period = app.PollingIntervalsSpinner.Value;
        end

        % Value changed function: NumberofCellsSpinner
        function NumberofCellsSpinnerValueChanged(app, event)
            value = app.NumberofCellsSpinner.Value;
            while value ~= app.numOfCells
                if value > app.numOfCells
                    app.numOfCells = app.numOfCells + 1;
                    app.ListCell.Children(end+1) = uitreenode(app.ListCell, Text = "Cell " + int2str(app.numOfCells));
                    app.ListCell.CheckedNodes(end+1) = app.ListCell.Children(end);
                else
                    app.ListCell.CheckedNodes = app.ListCell.CheckedNodes(app.ListCell.CheckedNodes ~= app.ListCell.Children(end));
                    app.numOfCells = app.numOfCells - 1;
                    app.ListCell.Children(end).delete;
                end
            end
        end

        % Value changed function: LogtoFileSwitch
        function LogtoFileSwitchValueChanged(app, event)
            if strcmp(app.LogtoFileSwitch.Value, "On")
                currentTimeStamp = string(datetime);
                currentTimeStamp = strrep(currentTimeStamp,':','_');
                currentTimeStamp = strrep(currentTimeStamp,'-','_');
                currentTimeStamp = strrep(currentTimeStamp,' ','_');
                [app.logFile, app.logFilePath] = uiputfile(app.userProfile + "\Documents\Battery Monitor\" + "Battery_Monitor_Log_" + currentTimeStamp + ".log", 'Select Log File Location');
                if app.logFile ~= 0
                    app.logging = true;
                    app.LogFileLocationDisplay.Value = string(app.logFilePath) + string(app.logFile);
                    app.LogFileLocationDisplay.Tooltip = string(app.logFilePath) + string(app.logFile);
                else
                    app.LogtoFileSwitch.Value = "Off";
                end
            else
                app.LogFileLocationDisplay.Value = "";
                app.LogFileLocationDisplay.Tooltip = "";
                app.logging = false;
                app.logFile = [];
                app.logFilePath = [];
            end
        end

        % Value changed function: BatteryChemistryDropDown
        function BatteryChemistryDropDownValueChanged(app, event)
            value = app.BatteryChemistryDropDown.Value;
            if value == "Li - Ion"
                app.ratedVoltageRange = [2.5, 4.2];
                app.ratedTempRange = [0 60];
            elseif value == "Li - Poly"
                app.ratedVoltageRange = [3.0, 4.2];
                app.ratedTempRange = [-10 60];
            elseif value == "Li - FePO4"
                app.ratedVoltageRange = [2.5, 3.6];
                app.ratedTempRange = [0 60];
            end
        end

        % Close request function: BatteryMonitorPCUIFigure
        function BatteryMonitorPCUIFigureCloseRequest(app, event)
            if app.running
                write(app.serialConnection, "0", "char")
                app.serialConnection = [];
                if strcmp(app.LogtoFileSwitch.Value, "On")
                    fclose(app.logFileStruct);
                end
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BatteryMonitorPCUIFigure = uifigure('Visible', 'off');
            app.BatteryMonitorPCUIFigure.Name = 'Battery Monitor PC';
            app.BatteryMonitorPCUIFigure.Icon = fullfile(pathToMLAPP, 'BatIcon.png');
            app.BatteryMonitorPCUIFigure.CloseRequestFcn = createCallbackFcn(app, @BatteryMonitorPCUIFigureCloseRequest, true);
            app.PackStats = uiaxes(app.BatteryMonitorPCUIFigure);
            title(app.PackStats, 'Pack Voltage and Current')
            xlabel(app.PackStats, 'Time (s)')
            ylabel(app.PackStats, 'Voltage (V)')
            zlabel(app.PackStats, 'Z')
            app.PackStats.ZTickLabel = '';
            app.TempGraph = uiaxes(app.BatteryMonitorPCUIFigure);
            title(app.TempGraph, 'Cell Temperature')
            xlabel(app.TempGraph, 'Time (s)')
            ylabel(app.TempGraph, 'Temperature (deg. C)')
            zlabel(app.TempGraph, 'Z')
            app.VoltageGraph = uiaxes(app.BatteryMonitorPCUIFigure);
            title(app.VoltageGraph, 'Cell Voltage')
            xlabel(app.VoltageGraph, 'Time (s)')
            ylabel(app.VoltageGraph, 'Voltage (V)')
            zlabel(app.VoltageGraph, 'Z')
            app.ListCell = uitree(app.BatteryMonitorPCUIFigure, 'checkbox');
            app.ListCell.Tooltip = {'Select for which cells in the battery pack to display voltage and temperature data. Data is recorded for all cells at all times during a run and displaying data on the graphs can be toggeld on the fly using the checkboxes.'};
            app.Cell1 = uitreenode(app.ListCell);
            app.Cell1.NodeData = 1;
            app.Cell1.Text = 'Cell 1';
            app.Cell2 = uitreenode(app.ListCell);
            app.Cell2.NodeData = 2;
            app.Cell2.Text = 'Cell 2';
            app.Cell3 = uitreenode(app.ListCell);
            app.Cell3.NodeData = 3;
            app.Cell3.Text = 'Cell 3';
            app.Cell4 = uitreenode(app.ListCell);
            app.Cell4.NodeData = 4;
            app.Cell4.Text = 'Cell 4';
            app.Cell5 = uitreenode(app.ListCell);
            app.Cell5.NodeData = 5;
            app.Cell5.Text = 'Cell 5';
            app.Cell6 = uitreenode(app.ListCell);
            app.Cell6.NodeData = 6;
            app.Cell6.Text = 'Cell 6';
            app.Cell7 = uitreenode(app.ListCell);
            app.Cell7.NodeData = 7;
            app.Cell7.Text = 'Cell 7';
            app.Cell8 = uitreenode(app.ListCell);
            app.Cell8.NodeData = 8;
            app.Cell8.Text = 'Cell 8';
            % Assign Checked Nodes
            app.ListCell.CheckedNodes = [app.Cell1, app.Cell2, app.Cell3, app.Cell4, app.Cell5, app.Cell6, app.Cell7, app.Cell8];
            app.CellSelectLabel = uilabel(app.BatteryMonitorPCUIFigure);
            app.CellSelectLabel.Text = 'Cell Select:';
            app.TabGroup = uitabgroup(app.BatteryMonitorPCUIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.NumberofCellsSpinnerLabel = uilabel(app.ConfigurationTab);
            app.NumberofCellsSpinnerLabel.Text = 'Number of Cells:';
            app.NumberofCellsSpinner = uispinner(app.ConfigurationTab);
            app.NumberofCellsSpinner.Limits = [3 16];
            app.NumberofCellsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofCellsSpinnerValueChanged, true);
            app.NumberofCellsSpinner.Tooltip = {'Set the number of cells in the battery pack. Min: 3; Max: 16.'};
            app.NumberofCellsSpinner.Value = 8;
            app.COMPortsListBoxLabel = uilabel(app.ConfigurationTab);
            app.COMPortsListBoxLabel.Text = 'COM Ports:';
            app.COMPortsListBox = uilistbox(app.ConfigurationTab);
            app.COMPortsListBox.Items = {};
            app.COMPortsListBox.Tooltip = {'Select target COM port to connect to.'};
            app.COMPortsListBox.Value = {};
            app.RefreshButton = uibutton(app.ConfigurationTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Tooltip = {'Check for available COM ports.'};
            app.RefreshButton.Text = 'Refresh';
            app.ConnectButton = uibutton(app.ConfigurationTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Tooltip = {'Connect/Disconnect battery monitoring board via selected COM port.'};
            app.ConnectButton.Text = 'Connect';
            app.BatteryChemistryLabel = uilabel(app.ConfigurationTab);
            app.BatteryChemistryLabel.Text = 'Battery Chemistry:';
            app.BatteryChemistryDropDown = uidropdown(app.ConfigurationTab);
            app.BatteryChemistryDropDown.Items = {'Li - Ion', 'Li - Poly', 'Li - FePO4'};
            app.BatteryChemistryDropDown.ValueChangedFcn = createCallbackFcn(app, @BatteryChemistryDropDownValueChanged, true);
            app.BatteryChemistryDropDown.Tooltip = {'Select the exact chemistry of the battery under test. Sets the appropriate nominal voltages to use in calculations of other values.'};
            app.BatteryChemistryDropDown.Value = 'Li - Ion';
            app.BatteryCapacityAhLabel = uilabel(app.ConfigurationTab);
            app.BatteryCapacityAhLabel.Text = 'Battery Capacity (mAh):';
            app.BatteryCapacitymAhEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.BatteryCapacitymAhEditField.Limits = [0 Inf];
            app.BatteryCapacitymAhEditField.Tooltip = {'Enter the energy capacity of the total battery pack (not just 1 cell) in milli-ampere-hours.'};
            app.BatteryCapacitymAhEditField.Value = 1000;
            app.PollingIntervalsSpinnerLabel = uilabel(app.ConfigurationTab);
            app.PollingIntervalsSpinnerLabel.Text = 'Polling Interval (s):';
            app.PollingIntervalsSpinner = uispinner(app.ConfigurationTab);
            app.PollingIntervalsSpinner.Step = 0.1;
            app.PollingIntervalsSpinner.Limits = [0.2 60];
            app.PollingIntervalsSpinner.ValueChangedFcn = createCallbackFcn(app, @PollingIntervalsSpinnerValueChanged, true);
            app.PollingIntervalsSpinner.Tooltip = {'Select the polling period for data collection. Minimum value is 0.2s.'};
            app.PollingIntervalsSpinner.Value = 0.5;
            app.CurrentSOCEditFieldLabel = uilabel(app.ConfigurationTab);
            app.CurrentSOCEditFieldLabel.Text = 'Current SOC (%):';
            app.CurrentSOCEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.CurrentSOCEditField.Limits = [0 100];
            app.CurrentSOCEditField.Tooltip = {'If current state of charge of the battery under test is known, enter it here in % of nominal capacity. Otherwise leave 0.'};
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.PowerWGaugeLabel = uilabel(app.RunTab);
            app.PowerWGaugeLabel.Text = 'Power (W)';
            app.PowerWGauge = uigauge(app.RunTab, 'circular');
            app.PowerWGauge.Limits = [-100 100];
            app.EstimatedSOCGaugeLabel = uilabel(app.RunTab);
            app.EstimatedSOCGaugeLabel.Text = 'Estimated SOC (%)';
            app.EstimatedSOCGauge = uigauge(app.RunTab, 'circular');
            app.EstimatedSOCGauge.Tooltip = {'Indicates the estimated State of Charge of the battery pack using the appropriate method for the selected battery chemistry and the available data.'};
            app.TotalTimeElapsedLabel = uilabel(app.RunTab);
            app.TotalTimeElapsedLabel.Text = 'Total Time Elapsed:';
            app.TimeElapsed = uieditfield(app.RunTab, 'text');
            app.TimeElapsed.Editable = 'off';
            app.TimeElapsed.Tooltip = {'Total time elapsed since start of the run.'};
            app.TimeElapsed.Placeholder = '0:00:00';
            app.StatusLampLabel = uilabel(app.RunTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.RunTab);
            app.WLabel = uilabel(app.RunTab);
            app.WLabel.Text = '00.00 W';
            app.EnergyInmAhEditFieldLabel = uilabel(app.RunTab);
            app.EnergyInmAhEditFieldLabel.Text = 'Energy In (mAh):';
            app.EnergyInmAhEditField = uieditfield(app.RunTab, 'numeric');
            app.EnergyInmAhEditField.Editable = 'off';
            app.EnergyInmAhEditField.Tooltip = {'Sum of the energy stored in the battery since the start of the run.'};
            app.EnergyOutmAhEditFieldLabel = uilabel(app.RunTab);
            app.EnergyOutmAhEditFieldLabel.Text = 'Energy Out (mAh):';
            app.EnergyOutmAhEditField = uieditfield(app.RunTab, 'numeric');
            app.EnergyOutmAhEditField.Editable = 'off';
            app.EnergyOutmAhEditField.Tooltip = {'Sum of the energy depleted from the battery since the start of the run.'};
            app.PowerFlowEditFieldLabel = uilabel(app.RunTab);
            app.PowerFlowEditFieldLabel.Text = 'Power Flow:';
            app.PowerFlowEditField = uieditfield(app.RunTab, 'text');
            app.PowerFlowEditField.Editable = 'off';
            app.PowerFlowEditField.Tooltip = {'Indicates the direction of current flow relative to the battery pack.'};
            app.PowerFlowEditField.Placeholder = 'Idle';
            app.DischargeTimeEditFieldLabel = uilabel(app.RunTab);
            app.DischargeTimeEditFieldLabel.Text = 'Discharge Time:';
            app.DischargeTimeEditField = uieditfield(app.RunTab, 'text');
            app.DischargeTimeEditField.Editable = 'off';
            app.DischargeTimeEditField.Tooltip = {'Total time spent discharging the battery since start of the run.'};
            app.DischargeTimeEditField.Placeholder = '0:00:00';
            app.ChargeTimeEditFieldLabel = uilabel(app.RunTab);
            app.ChargeTimeEditFieldLabel.Text = 'Charge Time:';
            app.ChargeTimeEditField = uieditfield(app.RunTab, 'text');
            app.ChargeTimeEditField.Editable = 'off';
            app.ChargeTimeEditField.Tooltip = {'Total time spent charging the battery since start of the run.'};
            app.ChargeTimeEditField.Placeholder = '0:00:00';
            app.RunButton = uibutton(app.RunTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Tooltip = {'Start/Stop monitoring run.'};
            app.RunButton.Text = 'Start';
            app.LogtoFileSwitchLabel = uilabel(app.RunTab);
            app.LogtoFileSwitchLabel.Enable = 'off';
            app.LogtoFileSwitchLabel.Text = 'Log to File:';
            app.LogtoFileSwitch = uiswitch(app.RunTab, 'slider');
            app.LogtoFileSwitch.ValueChangedFcn = createCallbackFcn(app, @LogtoFileSwitchValueChanged, true);
            app.LogtoFileSwitch.Enable = 'off';
            app.LogtoFileSwitch.Tooltip = {'Enable/Disable logging run data to file. When enabled, opens a prompt to select log file name and location.'};
            app.LogtoFileLocation = uilabel(app.RunTab);
            app.LogtoFileLocation.Text = 'Log to:';
            app.LogFileLocationDisplay = uitextarea(app.RunTab);
            app.LogFileLocationDisplay.Editable = 'off';
            app.LogFileLocationDisplay.Placeholder = '<path_to_file>';
            app.CellVoltagesTab = uitab(app.TabGroup);
            app.CellVoltagesTab.Title = 'Cell Voltages';
            app.CellVoltageVLabel = uilabel(app.CellVoltagesTab);
            app.CellVoltageVLabel.Text = 'Cell Voltage (V)';
            app.Cell01EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell01EditFieldLabel.Text = 'Cell 01:';
            app.Cell01EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell01EditField.Editable = 'off';
            app.Cell02EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell02EditFieldLabel.Text = 'Cell 02:';
            app.Cell02EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell02EditField.Editable = 'off';
            app.Cell03EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell03EditFieldLabel.Text = 'Cell 03:';
            app.Cell03EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell03EditField.Editable = 'off';
            app.Cell04EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell04EditFieldLabel.Text = 'Cell 04:';
            app.Cell04EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell04EditField.Editable = 'off';
            app.Cell05EditField_3Label = uilabel(app.CellVoltagesTab);
            app.Cell05EditField_3Label.Text = 'Cell 05:';
            app.Cell05EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell05EditField.Editable = 'off';
            app.Cell06EditField_3Label = uilabel(app.CellVoltagesTab);
            app.Cell06EditField_3Label.Text = 'Cell 06:';
            app.Cell06EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell06EditField.Editable = 'off';
            app.Cell07EditField_2Label = uilabel(app.CellVoltagesTab);
            app.Cell07EditField_2Label.Text = 'Cell 07:';
            app.Cell07EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell07EditField.Editable = 'off';
            app.Cell08EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell08EditFieldLabel.Text = 'Cell 08:';
            app.Cell08EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell08EditField.Editable = 'off';
            app.Cell09EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell09EditFieldLabel.Text = 'Cell 09:';
            app.Cell09EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell09EditField.Editable = 'off';
            app.Cell10EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell10EditFieldLabel.Text = 'Cell 10:';
            app.Cell10EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell10EditField.Editable = 'off';
            app.Cell11EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell11EditFieldLabel.Text = 'Cell 11:';
            app.Cell11EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell11EditField.Editable = 'off';
            app.Cell12EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell12EditFieldLabel.Text = 'Cell 12:';
            app.Cell12EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell12EditField.Editable = 'off';
            app.Cell13EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell13EditFieldLabel.Text = 'Cell 13:';
            app.Cell13EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell13EditField.Editable = 'off';
            app.Cell14EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell14EditFieldLabel.Text = 'Cell 14:';
            app.Cell14EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell14EditField.Editable = 'off';
            app.Cell15EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell15EditFieldLabel.Text = 'Cell 15:';
            app.Cell15EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell15EditField.Editable = 'off';
            app.Cell16EditFieldLabel = uilabel(app.CellVoltagesTab);
            app.Cell16EditFieldLabel.Text = 'Cell 16:';
            app.Cell16EditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.Cell16EditField.Editable = 'off';
            app.MinCellLabel = uilabel(app.CellVoltagesTab);
            app.MinCellLabel.Text = 'Min Cell (#):';
            app.MinCellEditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.MinCellEditField.Editable = 'off';
            app.MinCellEditField.Tooltip = {'Lowest voltage cell in the battery pack.'};
            app.MaxCellLabel = uilabel(app.CellVoltagesTab);
            app.MaxCellLabel.Text = 'Max Cell (#):';
            app.MaxCellEditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.MaxCellEditField.Editable = 'off';
            app.MaxCellEditField.Tooltip = {'Highest voltage cell in the battery pack.'};
            app.CellDeltaVEditFieldLabel = uilabel(app.CellVoltagesTab);
            app.CellDeltaVEditFieldLabel.Text = 'Cell Delta (V):';
            app.CellDeltaVEditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.CellDeltaVEditField.Editable = 'off';
            app.CellDeltaVEditField.Tooltip = {'Difference between the highest and lowest cell voltage.'};
            app.CellMeanVEditFieldLabel = uilabel(app.CellVoltagesTab);
            app.CellMeanVEditFieldLabel.Text = 'Cell Mean (V):';
            app.CellMeanVEditField = uieditfield(app.CellVoltagesTab, 'numeric');
            app.CellMeanVEditField.Editable = 'off';
            app.CellMeanVEditField.Tooltip = {'Average cell voltage in the battery pack.'};
            app.CellTempsTab = uitab(app.TabGroup);
            app.CellTempsTab.Title = 'Cell Temps';
            app.CellTemperatureBT1degCLabel = uilabel(app.CellTempsTab);
            app.CellTemperatureBT1degCLabel.Text = 'Cell Temperature BT1 (deg. C)';
            app.CellTemperatureBT2degCLabel = uilabel(app.CellTempsTab);
            app.CellTemperatureBT2degCLabel.Text = 'Cell Temperature BT2 (deg. C)';
            app.Cell01EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell01EditField_2Label_2.Text = 'Cell 01:';
            app.Cell01EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell01EditField_t_1.Editable = 'off';
            app.Cell02EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell02EditField_2Label_2.Text = 'Cell 02:';
            app.Cell02EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell02EditField_t_1.Editable = 'off';
            app.Cell03EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell03EditField_2Label_2.Text = 'Cell 03:';
            app.Cell03EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell03EditField_t_1.Editable = 'off';
            app.Cell04EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell04EditField_2Label_2.Text = 'Cell 04:';
            app.Cell04EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell04EditField_t_1.Editable = 'off';
            app.Cell05EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell05EditField_2Label_2.Text = 'Cell 05:';
            app.Cell05EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell05EditField_t_1.Editable = 'off';
            app.Cell06EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell06EditField_2Label_2.Text = 'Cell 06:';
            app.Cell06EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell06EditField_t_1.Editable = 'off';
            app.Cell07EditField_3Label = uilabel(app.CellTempsTab);
            app.Cell07EditField_3Label.Text = 'Cell 07:';
            app.Cell07EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell07EditField_t_1.Editable = 'off';
            app.Cell08EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell08EditField_2Label_2.Text = 'Cell 08:';
            app.Cell08EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell08EditField_t_1.Editable = 'off';
            app.Cell09EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell09EditField_2Label_2.Text = 'Cell 09:';
            app.Cell09EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell09EditField_t_1.Editable = 'off';
            app.Cell10EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell10EditField_2Label_2.Text = 'Cell 10:';
            app.Cell10EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell10EditField_t_1.Editable = 'off';
            app.Cell11EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell11EditField_2Label_2.Text = 'Cell 11:';
            app.Cell11EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell11EditField_t_1.Editable = 'off';
            app.Cell12EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell12EditField_2Label_2.Text = 'Cell 12:';
            app.Cell12EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell12EditField_t_1.Editable = 'off';
            app.Cell13EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell13EditField_2Label_2.Text = 'Cell 13:';
            app.Cell13EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell13EditField_t_1.Editable = 'off';
            app.Cell14EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell14EditField_2Label_2.Text = 'Cell 14:';
            app.Cell14EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell14EditField_t_1.Editable = 'off';
            app.Cell15EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell15EditField_2Label_2.Text = 'Cell 15:';
            app.Cell15EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell15EditField_t_1.Editable = 'off';
            app.Cell16EditField_2Label_2 = uilabel(app.CellTempsTab);
            app.Cell16EditField_2Label_2.Text = 'Cell 16:';
            app.Cell16EditField_t_1 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell16EditField_t_1.Editable = 'off';
            app.Cell01EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell01EditField_2Label_3.Text = 'Cell 01:';
            app.Cell01EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell01EditField_t_2.Editable = 'off';
            app.Cell02EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell02EditField_2Label_3.Text = 'Cell 02:';
            app.Cell02EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell02EditField_t_2.Editable = 'off';
            app.Cell03EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell03EditField_2Label_3.Text = 'Cell 03:';
            app.Cell03EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell03EditField_t_2.Editable = 'off';
            app.Cell04EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell04EditField_2Label_3.Text = 'Cell 04:';
            app.Cell04EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell04EditField_t_2.Editable = 'off';
            app.Cell05EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell05EditField_2Label_3.Text = 'Cell 05:';
            app.Cell05EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell05EditField_t_2.Editable = 'off';
            app.Cell06EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell06EditField_2Label_3.Text = 'Cell 06:';
            app.Cell06EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell06EditField_t_2.Editable = 'off';
            app.Cell07EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell07EditField_2Label_3.Text = 'Cell 07:';
            app.Cell07EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell07EditField_t_2.Editable = 'off';
            app.Cell08EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell08EditField_2Label_3.Text = 'Cell 08:';
            app.Cell08EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell08EditField_t_2.Editable = 'off';
            app.Cell09EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell09EditField_2Label_3.Text = 'Cell 09:';
            app.Cell09EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell09EditField_t_2.Editable = 'off';
            app.Cell10EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell10EditField_2Label_3.Text = 'Cell 10:';
            app.Cell10EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell10EditField_t_2.Editable = 'off';
            app.Cell11EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell11EditField_2Label_3.Text = 'Cell 11:';
            app.Cell11EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell11EditField_t_2.Editable = 'off';
            app.Cell12EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell12EditField_2Label_3.Text = 'Cell 12:';
            app.Cell12EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell12EditField_t_2.Editable = 'off';
            app.Cell13EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell13EditField_2Label_3.Text = 'Cell 13:';
            app.Cell13EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell13EditField_t_2.Editable = 'off';
            app.Cell14EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell14EditField_2Label_3.Text = 'Cell 14:';
            app.Cell14EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell14EditField_t_2.Editable = 'off';
            app.Cell15EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell15EditField_2Label_3.Text = 'Cell 15:';
            app.Cell15EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell15EditField_t_2.Editable = 'off';
            app.Cell16EditField_2Label_3 = uilabel(app.CellTempsTab);
            app.Cell16EditField_2Label_3.Text = 'Cell 16:';
            app.Cell16EditField_t_2 = uieditfield(app.CellTempsTab, 'numeric');
            app.Cell16EditField_t_2.Editable = 'off';
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.TODOLabel = uilabel(app.LoadTab);
            app.TODOLabel.Text = 'TODO';
            app.LogsTab = uitab(app.TabGroup);
            app.LogsTab.Title = 'Logs';
            app.TODOLabel_2 = uilabel(app.LogsTab);
            app.TODOLabel_2.Text = 'TODO';
            % Show the figure after all components are created
            app.BatteryMonitorPCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatteryMonitorPC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BatteryMonitorPCUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BatteryMonitorPCUIFigure)
        end
    end
end